classdef AnalizadorDeEspectro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalizadordeespectroLabel  matlab.ui.control.Label
        tiempo                     matlab.ui.control.UIAxes
        frecuencia                 matlab.ui.control.UIAxes
        frecuenciaRecortada        matlab.ui.control.UIAxes
        generar                    matlab.ui.control.Button
        MaximomultiplicadordeFourierLabel  matlab.ui.control.Label
        multiplicadorFourier       matlab.ui.control.NumericEditField
        CantidaddePeriodosEditFieldLabel  matlab.ui.control.Label
        periodos                   matlab.ui.control.NumericEditField
        ConfiguracionesLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generar
        function generarButtonPushed(app, event)
            % Entradas de datos
            frecuencia = app.multiplicadorFourier.Value;
            repeticiones = 17; % numero impar
            resolucion = 1000;
            cantidadPeriodos = app.periodos.Value;
            
            % Procesos para obtenerla en funcion del tiempo
            tiempo = linspace(0,cantidadPeriodos/frecuencia,resolucion);
            
            for coeficiente = 1.0:+2.0:repeticiones
                componente = (1/coeficiente)*sin(2*pi*coeficiente*frecuencia*tiempo);
                if coeficiente == 1
                    cuadrada = componente;
                else
                   cuadrada = cuadrada + componente; 
                end       
            end
            
            % Proceso para obtenerla en funcion de la frecuencia (Hz)
            % En este caso se usa fft (Fast Fourier Transform)
            % el valor absoluto deja manejable la trasnformada, que naturalmente esta
            % en modo complejo
            
            cuadradadaFourier = abs(fftshift(fft(cuadrada)));
            
            
            % Impresiones de datos
            % falta crear un valor para el valor x con una frecuencia escalada en
            % 10
            
            plot(app.tiempo,tiempo,cuadrada)
            grid on
            
            plot(app.frecuencia,cuadradadaFourier(475:530))
            grid on
            
            plot(app.frecuenciaRecortada,cuadradadaFourier(475:530))
            grid on
            
            close all
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AnalizadordeespectroLabel = uilabel(app.UIFigure);
            app.AnalizadordeespectroLabel.Text = 'Analizador de espectro';
            app.tiempo = uiaxes(app.UIFigure);
            title(app.tiempo, 'Serie de Fourier en funcion del tiempo')
            xlabel(app.tiempo, 'Tiempo')
            ylabel(app.tiempo, 'Amplitud')
            app.frecuencia = uiaxes(app.UIFigure);
            title(app.frecuencia, 'Serie de Fourier en funcion de la frecuencia')
            xlabel(app.frecuencia, 'Frecuencia')
            ylabel(app.frecuencia, 'Amplitud')
            app.frecuenciaRecortada = uiaxes(app.UIFigure);
            title(app.frecuenciaRecortada, 'Serie de Fourier en funcion de la frecuencia (recortada)')
            xlabel(app.frecuenciaRecortada, 'Frecuencia')
            ylabel(app.frecuenciaRecortada, 'Amplitud')
            app.generar = uibutton(app.UIFigure, 'push');
            app.generar.ButtonPushedFcn = createCallbackFcn(app, @generarButtonPushed, true);
            app.generar.Text = {'Generar gráficas'; ''};
            app.MaximomultiplicadordeFourierLabel = uilabel(app.UIFigure);
            app.MaximomultiplicadordeFourierLabel.Text = 'Maximo multiplicador de Fourier';
            app.multiplicadorFourier = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddePeriodosEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddePeriodosEditFieldLabel.Text = 'Cantidad de Periodos';
            app.periodos = uieditfield(app.UIFigure, 'numeric');
            app.ConfiguracionesLabel = uilabel(app.UIFigure);
            app.ConfiguracionesLabel.Text = 'Configuraciones';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalizadorDeEspectro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end