classdef TransmisorAnalogo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TransmisoranlogoLabel     matlab.ui.control.Label
        analoga                   matlab.ui.control.UIAxes
        carrier                   matlab.ui.control.UIAxes
        am                        matlab.ui.control.UIAxes
        fm                        matlab.ui.control.UIAxes
        pm                        matlab.ui.control.UIAxes
        ConfiguracionesLabel      matlab.ui.control.Label
        generar                   matlab.ui.control.Button
        VoltajecarrierLabel       matlab.ui.control.Label
        voltaje                   matlab.ui.control.NumericEditField
        CantidaddePeriodosparaelcarrierLabel  matlab.ui.control.Label
        periodosCarrier           matlab.ui.control.NumericEditField
        VoltajemodulacionesLabel  matlab.ui.control.Label
        voltajeModulacion         matlab.ui.control.NumericEditField
        CantidaddePeriodosparalasmodulacionesLabel  matlab.ui.control.Label
        periodosModulacion        matlab.ui.control.NumericEditField
        IndicedemodulacionAM04EditFieldLabel  matlab.ui.control.Label
        modAM                     matlab.ui.control.NumericEditField
        IndicedemodulacionFM02EditFieldLabel  matlab.ui.control.Label
        modFM                     matlab.ui.control.NumericEditField
        IndicedemodulacionPM02EditFieldLabel  matlab.ui.control.Label
        modPM                     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generar
        function generarButtonPushed(app, event)
                %carrier
                Ac = app.voltaje.Value;
                fc = 1000e3;
                thetac = 0;
                cpc = app.periodosCarrier.Value;
                
                %xt
                Ax = app.voltajeModulacion.Value;
                fx = 3400;
                thetax = 0;
                cpx = app.periodosModulacion.Value;
                
                %indice modulacion 0 <= m <= 0.4 
                m = app.modAM.Value;
                %indice modulacion M, npm >=2
                nfm = app.modFM.Value;
                %indice modulacion PM, npm >=2
                npm = app.modPM.Value;
                
                %--------PROCESS------
                %carrier
                tc = linspace(0,cpc/fc,500);
                carrier = Ac*sin(2*pi*fc*tc + thetac);
                
                %xt
                tx = linspace(0,cpx/fx,500);
                xt = Ax*sin(2*pi*fx*tx + thetax);
                
                %Modulacion AM
                xam = (1+m*xt).*carrier;
                
                %modulacion FM
                xfm = Ac*sin(2*pi*fc*tc + nfm*xt);
                
                
                %modulacion PM
                xt_diff = Ax*sin(2*pi*fx*tx + (thetax+pi/2));
                xpm = Ac*sin(2*pi*fc*tc + npm*xt_diff);
                
                %--------OUTPUT-------
                
                plot(app.analoga,tx,xt)
                grid on
                
                plot(app.carrier,tc,carrier)
                grid on
                
                plot(app.am,tc,xam)
                grid on
                
                plot(app.fm,tc,xfm)
                grid on
                
                plot(app.pm,tc,xpm)
                grid on
                
                close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TransmisoranlogoLabel = uilabel(app.UIFigure);
            app.TransmisoranlogoLabel.Text = 'Transmisor análogo';
            app.analoga = uiaxes(app.UIFigure);
            title(app.analoga, 'Señal análoga')
            xlabel(app.analoga, 'Tiempo')
            ylabel(app.analoga, 'Amplitud')
            app.carrier = uiaxes(app.UIFigure);
            title(app.carrier, 'Señal portadora del carrier')
            xlabel(app.carrier, 'Tiempo')
            ylabel(app.carrier, 'Amplitud')
            app.am = uiaxes(app.UIFigure);
            title(app.am, 'Modulación de la Amplitud AM')
            xlabel(app.am, 'Tiempo')
            ylabel(app.am, 'Amplitud')
            app.fm = uiaxes(app.UIFigure);
            title(app.fm, 'Modulación de la Frecuencia FM')
            xlabel(app.fm, 'Tiempo')
            ylabel(app.fm, 'Amplitud')
            app.pm = uiaxes(app.UIFigure);
            title(app.pm, 'Modulación del Pulso PM')
            xlabel(app.pm, 'Tiempo')
            ylabel(app.pm, 'Amplitud')
            app.ConfiguracionesLabel = uilabel(app.UIFigure);
            app.ConfiguracionesLabel.Text = 'Configuraciones';
            app.generar = uibutton(app.UIFigure, 'push');
            app.generar.ButtonPushedFcn = createCallbackFcn(app, @generarButtonPushed, true);
            app.generar.Text = {'Generar gráficas'; ''};
            app.VoltajecarrierLabel = uilabel(app.UIFigure);
            app.VoltajecarrierLabel.Text = 'Voltaje carrier';
            app.voltaje = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddePeriodosparaelcarrierLabel = uilabel(app.UIFigure);
            app.CantidaddePeriodosparaelcarrierLabel.Text = 'Cantidad de Periodos para el carrier';
            app.periodosCarrier = uieditfield(app.UIFigure, 'numeric');
            app.VoltajemodulacionesLabel = uilabel(app.UIFigure);
            app.VoltajemodulacionesLabel.Text = 'Voltaje modulaciones';
            app.voltajeModulacion = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddePeriodosparalasmodulacionesLabel = uilabel(app.UIFigure);
            app.CantidaddePeriodosparalasmodulacionesLabel.Text = 'Cantidad de Periodos para las modulaciones';
            app.periodosModulacion = uieditfield(app.UIFigure, 'numeric');
            app.IndicedemodulacionAM04EditFieldLabel = uilabel(app.UIFigure);
            app.IndicedemodulacionAM04EditFieldLabel.Text = 'Indice de modulacion AM (0-4)';
            app.modAM = uieditfield(app.UIFigure, 'numeric');
            app.IndicedemodulacionFM02EditFieldLabel = uilabel(app.UIFigure);
            app.IndicedemodulacionFM02EditFieldLabel.Text = 'Indice de modulacion FM (0-2)';
            app.modFM = uieditfield(app.UIFigure, 'numeric');
            app.IndicedemodulacionPM02EditFieldLabel = uilabel(app.UIFigure);
            app.IndicedemodulacionPM02EditFieldLabel.Text = 'Indice de modulacion PM (0-2)';
            app.modPM = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransmisorAnalogo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end