classdef codificadorDeSenalesPolares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CodificacinLabel         matlab.ui.control.Label
        desealesdigitalesLabel   matlab.ui.control.Label
        resultado                matlab.ui.control.Label
        TextoacodificarLabel     matlab.ui.control.Label
        texto                    matlab.ui.control.EditField
        ResultadoenbinarioLabel  matlab.ui.control.Label
        codificar                matlab.ui.control.Button
        polar                    matlab.ui.control.UIAxes
        PotenciaenVoltiosLabel   matlab.ui.control.Label
        altura                   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: codificar
        function codificarButtonPushed(app, event)

            regla_bit_alto = 0;
            
            % --- PROCESS---
            ascii = double (app.texto.Value);
            bits = dec2bin(ascii);
            cadena = reshape(bits',1,[]);
            
            %app.Start.Editable = 'off';
            %app.Start.Visible  = 'off';
            
            app.resultado.Text = cadena;
            
            tb = 200;
            
            unos = ones(1,tb);
            
            ceros = zeros(1,tb);
            
            frame = [];
            frameinv = [];
            
            for n=1:length(cadena)
                
                if (cadena (n)== '1')
                    frame = [ frame unos ]; %#ok<*AGROW> 
                    frameinv = [ frameinv ceros ];
                
                else
                    frame = [ frame  ceros ];
                    frameinv = [ frameinv unos ];
                end 
            end 
            
            altura = app.altura.Value; %#ok<*ADPROPLC> 
            
            if (regla_bit_alto ==1)
                cadena_bits = altura*frame;
            else 
                cadena_bits = altura*frameinv; 
                
            end
            
            
            %---OUTPUT---
            plot(app.polar,cadena_bits)
            
            app.polar.XLimMode = 'manual';
            app.polar.XLim = [0 length(cadena_bits)];
            
            app.polar.YLimMode = 'manual';
            app.polar.YLim = [-1 altura+1];
            
            app.polar.XTick = ([(linspace(0,length(cadena_bits) , length(cadena)+1))]);
            app.polar.YTick = ([linspace(-1, altura+1,altura+3)]);
            
            app.polar.XGrid = 'on';
            app.polar.YGrid = 'on';
            
            
            close all
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CodificacinLabel = uilabel(app.UIFigure);
            app.CodificacinLabel.Text = {'Codificación'; ''};
            app.desealesdigitalesLabel = uilabel(app.UIFigure);
            app.desealesdigitalesLabel.Text = 'de señales digitales';
            app.resultado = uilabel(app.UIFigure);
            app.TextoacodificarLabel = uilabel(app.UIFigure);
            app.TextoacodificarLabel.Text = 'Texto a codificar:';
            app.texto = uieditfield(app.UIFigure, 'text');
            app.ResultadoenbinarioLabel = uilabel(app.UIFigure);
            app.ResultadoenbinarioLabel.Text = 'Resultado en binario:';
            app.codificar = uibutton(app.UIFigure, 'push');
            app.codificar.ButtonPushedFcn = createCallbackFcn(app, @codificarButtonPushed, true);
            app.codificar.Text = 'Convertir y codificar';
            app.polar = uiaxes(app.UIFigure);
            title(app.polar, 'Codificación Polar')
            xlabel(app.polar, 'X')
            ylabel(app.polar, 'Y')
            app.PotenciaenVoltiosLabel = uilabel(app.UIFigure);
            app.PotenciaenVoltiosLabel.Text = 'Potencia en Voltios:';
            app.altura = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = codificadorDeSenalesPolares

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end