classdef interfazParaRepeticiones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SeriesdeFourierLabel  matlab.ui.control.Label
        RepeticionesnumeroimparEditFieldLabel  matlab.ui.control.Label
        repeticiones          matlab.ui.control.NumericEditField
        creargrafica          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: repeticiones
        function repeticionesValueChanged(app, event)
            value = app.repeticiones.Value;
            
        end

        % Button pushed function: creargrafica
        function creargraficaButtonPushed(app, event)
                            % Entradas de datos
                frecuencia = 10e3;
                resolucion = 1000;
                repeticiones = app.repeticiones.Value
                cantidadPeriodos = 2;
                
                % Procesos para obtenerla en funcion del tiempo
                tiempo = linspace(0,cantidadPeriodos/frecuencia,resolucion);
                
                for coeficiente = 1.0:+2.0:repeticiones
                    componente = (1/coeficiente)*sin(2*pi*coeficiente*frecuencia*tiempo);
                    if coeficiente == 1
                        cuadrada = componente;
                    else
                       cuadrada = cuadrada + componente; 
                    end       
                end
                
                % Proceso para obtenerla en funcion de la frecuencia (Hz)
                % En este caso se usa fft (Fast Fourier Transform)
                % el valor absoluto deja manejable la trasnformada, que naturalmente esta
                % en modo complejo
                
                cuadradadaFourier = abs(fftshift(fft(cuadrada)));
                
                
                % Impresiones de datos
                % falta crear un valor para el valor x con una frecuencia escalada en
                % 10
                
                % texto = cat(2,'Serie de Fourier en función del tiempo, con: ', num2str(app.repeticiones));' repeticiones.';
                 % textoFourier = cat(2,'Serie de Fourier en función de la frecuencia, con: ', num2str(app.repeticiones));' repeticiones.';
                
                
                plot(app.UIAxes,tiempo,cuadrada)
                grid on
                
         
                plot(app.UIAxes2,cuadradadaFourier(475:530))
                grid on
                
                close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SeriesdeFourierLabel = uilabel(app.UIFigure);
            app.SeriesdeFourierLabel.Text = 'Series de Fourier';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Funcion del tiempo')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Voltaje')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Funcion de la frecuencia')
            xlabel(app.UIAxes2, 'Frecuencia')
            ylabel(app.UIAxes2, 'Voltaje')
            app.RepeticionesnumeroimparEditFieldLabel = uilabel(app.UIFigure);
            app.RepeticionesnumeroimparEditFieldLabel.Text = 'Repeticiones (numero impar)';
            app.repeticiones = uieditfield(app.UIFigure, 'numeric');
            app.repeticiones.ValueChangedFcn = createCallbackFcn(app, @repeticionesValueChanged, true);
            app.repeticiones.Tooltip = {'Recuerda que debe ser un numero impar'};
            app.creargrafica = uibutton(app.UIFigure, 'push');
            app.creargrafica.ButtonPushedFcn = createCallbackFcn(app, @creargraficaButtonPushed, true);
            app.creargrafica.Text = 'Crear gráfica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazParaRepeticiones

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end